classdef Aplicacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EscribirlargodeslizarparavariarellargodelasbarrasLabel  matlab.ui.control.Label
        BarraNegativaLabel        matlab.ui.control.Label
        BarraPositivaLabel        matlab.ui.control.Label
        MagnituddelasBarrasLabel  matlab.ui.control.Label
        CoordenadasLabel          matlab.ui.control.Label
        NegativoSpinner           matlab.ui.control.Spinner
        PositivoSpinner           matlab.ui.control.Spinner
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        YSpinner                  matlab.ui.control.Spinner
        YSpinnerLabel             matlab.ui.control.Label
        XSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel             matlab.ui.control.Label
        Positivo                  matlab.ui.control.Slider
        Negativo                  matlab.ui.control.Slider
        RightPanel                matlab.ui.container.Panel
        Texto                     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    %%
    %Omar Rodrigo Sorchini Puente   A01749389
    %Ariadna Huesca Coronado        A01749161
    %Miguel Ángel Pérez López       A01750145
    %Kevin Alexis Valdez Pérez      A01750478
    %%
    
    properties (Access = public)
        Property % Description
        b=[-1 -.61 2 .19];
        a=[-1 .41  2 .19];
        cx=0;
        cy=0;
    end
    
    methods (Access = private)
        
        function barras(app, cambio, p)
            switch cambio
                case "arriba"
                    app.a=[-p/2 .41 p .19];
                case "abajo"            
                    app.b=[-p/2 -.61 p .19];
            end
            [X,Y]=meshgrid([-1.8:.2:1.8]);
            cla(app.UIAxes)            
            puntoElectrico(app);
            [u,v,magnitud]=campoElectrico(app,app.a(1),app.b(1),true); 
            hold(app.UIAxes,"on")
            quiver(app.UIAxes,X,Y,u./magnitud,v./magnitud,"b","LineWidth",2)
            steam = streamslice(app.UIAxes,X,Y,u./magnitud,v./magnitud);
            set(steam,"Color","y")
            hold(app.UIAxes,"on")
            contour(app.UIAxes,X,Y,v,40)
            contour(app.UIAxes,X,Y,u,40)
            hold(app.UIAxes,"on")
            rectangle(app.UIAxes,'Position',app.b,'Curvature',1,"FaceColor",'k');
            hold(app.UIAxes,"on")
            rectangle(app.UIAxes,'Position',app.a,'Curvature',1,"FaceColor",'r');   
            hold(app.UIAxes,"on")
            scatter(app.UIAxes,app.cx,app.cy,"filled");
            hold(app.UIAxes,"off")
        end
        
        function [u,v,magnitud] = campoElectrico(app,a,b,puntos)
            %Arriba          
            
            if(puntos)
                [X,Y]=meshgrid([-1.8:.2:1.8]);        
            else
                X=app.XSpinner.Value;Y=app.YSpinner.Value;                
            end
            x=linspace(-a,a,100);y=.5;
            q1=1*(10^-3);
            [u,v]=campo(app,q1,x,y,X,Y);
            
            %Abajo
            x1=linspace(-b,b,100);y1=-.5;
            q2=-1*(10^-3);
            [u1,v1]=campo(app,q2,x1,y1,X,Y);
            u=u1+u
            v=v1+v;
            magnitud=(u.^2+v.^2).^(1/2);

        end
        
        function [u,v] = campo(app,q1,x,y,X,Y)
            u=[0];v=[0];k=9;
            for i=1:100
                distancia=(((X-x(1,i)).*.01).^2+((Y-y).*.01).^2).^(3/2);
                a=((((X-x(1,i)).*.01).*k*q1))./distancia;
                b=(((Y-y).*.01).*k*q1)./distancia;
                a(isnan(a)|isinf(a))=0;
                b(isnan(b)|isinf(b))=0;
                u= u + a;
                v= v + b;
            end
        end
        
        function puntoElectrico(app)
            [u,v,magnitud]=campoElectrico(app,app.a(1),app.b(1),false);
            app.Texto.Text=string("E("+app.cx+","+app.cy+") = "+u+"i + "+v+"j N/C");  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                barras(app, "arriba", 2)               
        end

        % Value changing function: Negativo
        function NegativoValueChanging(app, event)
            changingValue = event.Value;
            barras(app, "abajo", changingValue)            
            app.NegativoSpinner.Value=changingValue;
            %set(h2,'Visible','off')
        end

        % Value changing function: Positivo
        function PositivoValueChanging(app, event)
            changingValue = event.Value;
            barras(app, "arriba", changingValue)
            app.PositivoSpinner.Value=changingValue;
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            changingValue = event.Value;                 
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.cx=value;
            puntoElectrico(app);
            barras(app, "ninguno", 1)
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.cy=value;
            puntoElectrico(app);
            barras(app, "ninguno", 1)
        end

        % Value changed function: PositivoSpinner
        function PositivoSpinnerValueChanged(app, event)
            value = app.PositivoSpinner.Value;
            barras(app, "arriba", value)
            app.Positivo.Value=value;
            
        end

        % Value changed function: NegativoSpinner
        function NegativoSpinnerValueChanged(app, event)
            value = app.NegativoSpinner.Value;
            barras(app, "abajo", value)
            app.Negativo.Value=value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {531, 531};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {320, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Negativo = uislider(app.LeftPanel);
            app.Negativo.Limits = [0.5 3.6];
            app.Negativo.ValueChangingFcn = createCallbackFcn(app, @NegativoValueChanging, true);
            app.Negativo.Value = 2;
            app.Positivo = uislider(app.LeftPanel);
            app.Positivo.Limits = [0.5 3.6];
            app.Positivo.ValueChangingFcn = createCallbackFcn(app, @PositivoValueChanging, true);
            app.Positivo.Value = 2;
            app.XSpinnerLabel = uilabel(app.LeftPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.LeftPanel);
            app.XSpinner.Step = 0.1;
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.XSpinner.Limits = [-2 2];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.LeftPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.LeftPanel);
            app.YSpinner.Step = 0.1;
            app.YSpinner.Limits = [-2 2];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'red.png';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'black.png';
            app.PositivoSpinner = uispinner(app.LeftPanel);
            app.PositivoSpinner.Step = 0.05;
            app.PositivoSpinner.Limits = [0.5 3.6];
            app.PositivoSpinner.ValueChangedFcn = createCallbackFcn(app, @PositivoSpinnerValueChanged, true);
            app.PositivoSpinner.Value = 2;
            app.NegativoSpinner = uispinner(app.LeftPanel);
            app.NegativoSpinner.Step = 0.05;
            app.NegativoSpinner.Limits = [0.5 3.6];
            app.NegativoSpinner.ValueChangedFcn = createCallbackFcn(app, @NegativoSpinnerValueChanged, true);
            app.NegativoSpinner.Value = 2;
            app.CoordenadasLabel = uilabel(app.LeftPanel);
            app.CoordenadasLabel.Text = 'Coordenadas';
            app.MagnituddelasBarrasLabel = uilabel(app.LeftPanel);
            app.MagnituddelasBarrasLabel.Text = 'Magnitud de las Barras';
            app.BarraPositivaLabel = uilabel(app.LeftPanel);
            app.BarraPositivaLabel.Text = 'Barra Positiva:';
            app.BarraNegativaLabel = uilabel(app.LeftPanel);
            app.BarraNegativaLabel.Text = 'Barra Negativa:';
            app.EscribirlargodeslizarparavariarellargodelasbarrasLabel = uilabel(app.LeftPanel);
            app.EscribirlargodeslizarparavariarellargodelasbarrasLabel.Text = 'Escribir largo ó deslizar para variar el largo de las barras';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Campo Eléctrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-2.05 2.05];
            app.UIAxes.YLim = [-2.05 2.05];
            app.Texto = uilabel(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplicacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end